classdef NBS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NBodySimulator               matlab.ui.Figure
        MainLayout                   matlab.ui.container.GridLayout
        SettingsLayout               matlab.ui.container.GridLayout
        NumBodiesLayout              matlab.ui.container.GridLayout
        NumBodiesField               matlab.ui.control.Spinner
        NumofbodiesLabel             matlab.ui.control.Label
        DecorationLayout             matlab.ui.container.GridLayout
        TrailCheckBox                matlab.ui.control.CheckBox
        DecorationCheckBox           matlab.ui.control.CheckBox
        CollisionLayout              matlab.ui.container.GridLayout
        ElasticCheckBox              matlab.ui.control.CheckBox
        CollisionCheckBox            matlab.ui.control.CheckBox
        ExportLayout                 matlab.ui.container.GridLayout
        ExportPresets                matlab.ui.control.DropDown
        QualityLabel                 matlab.ui.control.Label
        ExportFPSField               matlab.ui.control.Spinner
        FPSLabel                     matlab.ui.control.Label
        RadiusMultplierSlider        matlab.ui.control.Slider
        RadiusmultiplierLabel        matlab.ui.control.Label
        ExportSimCheckBox            matlab.ui.control.CheckBox
        InfoLabel                    matlab.ui.control.Label
        StopButton                   matlab.ui.control.Button
        PPButton                     matlab.ui.control.StateButton
        ExportInfo                   matlab.ui.control.Label
        EXPORTLabel                  matlab.ui.control.Label
        DECORATIONLabel              matlab.ui.control.Label
        ElasticCollisionInfo         matlab.ui.control.Label
        COLLISIONLabel               matlab.ui.control.Label
        StepsPerTickSlider           matlab.ui.control.Slider
        StepspertickLabel            matlab.ui.control.Label
        TimeStepSlider               matlab.ui.control.Slider
        TimestepdtLabel              matlab.ui.control.Label
        GravSlider                   matlab.ui.control.Slider
        GravitationalConstantGLabel  matlab.ui.control.Label
        AutoConfigureCheckBox        matlab.ui.control.CheckBox
        PresetDropDown               matlab.ui.control.DropDown
        PRESETLabel                  matlab.ui.control.Label
        WelcomeScreenLayout          matlab.ui.container.GridLayout
        Image                        matlab.ui.control.Image
        EnrgInfoLayout               matlab.ui.container.GridLayout
        InfoLayout                   matlab.ui.container.GridLayout
        Label                        matlab.ui.control.Label
        DescriptionLayout            matlab.ui.container.GridLayout
        PresetDesc                   matlab.ui.control.Label
        PresetTitle                  matlab.ui.control.Label
        EnergyLayout                 matlab.ui.container.GridLayout
        EnrgAxes                     matlab.ui.control.UIAxes
        SimAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        welcomeScreenHidden,

        % Core
        N,
        pos,
        vel,
        mass,
        colors,
        radii
        trailX,
        trailY,
        trailLen
        bodyPlots,
        radiusMultiplier,
        trailLines
        G,
        dt,
        softening,
        simTime,
        stepCount
        simTimer,
        isRunning = false,
        isInit = false,
        stepsPerTick,
        lastFrameTime,

        % Stars
        starPlot,
        lastStarLimit,

        % Energy Plot
        KELine,
        PELine,
        TELine,
        energyHistory,
        timeHistory,                

        % Collision
        bodyRadius,

        % Sound
        ButtonAudioPlayer,

        %Video Writer
        PosHistory,
        isCancellingRender = false,
        isRenderingVideo,
        exportSnapTickInterval
    end
    
methods (Access = private)
        function initSimulation(app)
            %app.stopSim(false);
            
            app.initCollision();
            app.radiusMultiplier = app.RadiusMultplierSlider.Value;
            app.N = app.NumBodiesField.Value;
            app.G = app.GravSlider.Value;
            app.dt = app.TimeStepSlider.Value;
            app.stepsPerTick = app.StepsPerTickSlider.Value;
            app.trailLen = 600;
            app.simTime = 0;
            app.stepCount = 0;
            app.setupPreset(app.PresetDropDown.Value);
            app.setPresetDescription(app.PresetDropDown.Value);
            app.initGraphics();
            app.isInit = true;
            app.StepsPerTickSlider.Value = app.stepsPerTick;
            app.energyHistory = zeros(0, 3);
            app.timeHistory = [];
            app.initEnergyPlot();
            app.PosHistory = {};
        end

        function setupPreset(app, preset)
            app.mass = ones(app.N, 1);
            app.pos = zeros(app.N, 2);
            app.vel = zeros(app.N, 2);

            switch preset
                case 'Square 4'
                    setupGSdTTick(app, 1, 0, 0.002, 50, 0.4);
                    app.N = 4;
                    
                    app.mass = ones(app.N, 1); 
                    s = 3; % This is the distance from the axis to the corner
                
                    app.pos = zeros(app.N, 2);
                    app.pos(1,:) = [ s,  s]; % Top-Right
                    app.pos(2,:) = [-s,  s]; % Top-Left
                    app.pos(3,:) = [-s, -s]; % Bottom-Left
                    app.pos(4,:) = [ s, -s]; % Bottom-Right
                
                    % The distance from center to corner (Radius) is R = s * sqrt(2)
                    % The required velocity for this 4-body configuration is:
                    R = s * sqrt(2);
                    vMag = sqrt((app.G * 1 / R) * 0.603553);
                    
                    % Velocities must be perpendicular to the vector from the center
                    vComp = vMag / sqrt(2); % The x and y components of the velocity vector
                    
                    app.vel = zeros(app.N, 2);
                    app.vel(1,:) = [-vComp,  vComp]; % Moving Left-Up
                    app.vel(2,:) = [-vComp, -vComp]; % Moving Left-Down
                    app.vel(3,:) = [ vComp, -vComp]; % Moving Right-Down
                    app.vel(4,:) = [ vComp,  vComp]; % Moving Right-Up
                case 'Yarn'
                    setupGSdTTick(app, 1, 0, 0.001, 50, 0.4)
                    app.trailLen = 1200;

                    % Number of bodies
                    app.N = 3;
                    app.mass = ones(app.N, 1);
                    
                    % Positions
                    scale = 2;
                    app.pos = zeros(app.N, 2);
                    app.pos(1,:) = [-1,  0] * scale;
                    app.pos(2,:) = [ 1,  0] * scale;
                    app.pos(3,:) = [ 0,  0] * scale;
                    
                    % Velocities
                    vScale = 1 / sqrt(scale);
                    vx = 0.46444;
                    vy = 0.39606;
                    app.vel(1,:) = [ vx,  vy] * vScale;
                    app.vel(2,:) = [ vx,  vy] * vScale;
                    app.vel(3,:) = [-2*vx, -2*vy] * vScale;
                case 'Figure 8'
                    setupGSdTTick(app, 1, 0.01, 0.0001, 200, 0.3);
                    app.N = 3;
                    scale = 2;
                    
                    app.NumBodiesField.Value = 3;

                    % Set app.N again
                    app.mass = ones(app.N, 1); 
                    app.pos = zeros(app.N, 2);
                    app.vel = zeros(app.N, 2);

                    app.mass = ones(3, 1);

                    % Canonical figure-8 coordinates
                    app.pos(1,:) = [ 0.97000436,  -0.24308753] * scale;
                    app.pos(2,:) = [-0.97000436,   0.24308753] * scale;
                    app.pos(3,:) = [ 0.0,          0.0];

                    % Canonical figure-8 velocities
                    v3x = -0.93240737;
                    v3y = -0.86473146;
                    vScale = 1 / sqrt(scale);
                    app.vel(3,:) = [v3x,      v3y    ] * vScale;
                    app.vel(1,:) = [-v3x/2,  -v3y/2  ] * vScale;
                    app.vel(2,:) = [-v3x/2,  -v3y/2  ] * vScale;
                case 'Random Cluster'
                    setupGSdTTick(app, 1, 2.0, 0.01, 10, 1);
                    R = 25;                    
                    app.pos = (rand(app.N, 2) - 0.5) * 2 * R;
                    vScale = sqrt(app.G * app.N / R) * 0.4;
                    app.vel = (rand(app.N, 2) - 0.5) * vScale;

                case 'Solar System'
                    setupGSdTTick(app, 1, 0, 0.001, 20, 3);
                    app.N = min(app.N, 12);
                
                    app.mass = ones(app.N, 1) * 0.3;
                    app.mass(1) = 500;
                    app.pos = zeros(app.N, 2);
                    app.vel = zeros(app.N, 2);
                
                    % Planets spacing
                    rMin = 30;
                    rMax = 100;
                    if app.N > 2
                        radiii = logspace(log10(rMin), log10(rMax), app.N - 1);
                    else
                        radiii = rMin;
                    end
                
                    for i = 2:app.N
                        r = radiii(i - 1);
                        theta = rand() * 2 * pi;
                        app.pos(i,:) = [r * cos(theta), r * sin(theta)];
                        v = sqrt(app.G * app.mass(1) / r);
                        app.vel(i,:) = [-sin(theta), cos(theta)] * v;
                    end
                case 'Binary System'
                    setupGSdTTick(app, 1, 0.5, 0.002, 40, 3);
                
                    mStar = 300;
                    sep   = 20;   % wider binary separation so planets don't crowd the stars
                
                    app.mass = ones(app.N, 1) * 1.0;   % heavier planets so they're visible
                    app.mass(1:2) = mStar;
                    app.pos = zeros(app.N, 2);
                    app.vel = zeros(app.N, 2);
                
                    % Binary stars
                    app.pos(1,:) = [-sep, 0];
                    app.pos(2,:) = [ sep, 0];
                    vOrbit = sqrt(app.G * mStar / (4 * sep));
                    app.vel(1,:) = [0,  vOrbit];
                    app.vel(2,:) = [0, -vOrbit];
                
                    % Circumbinary planets — logarithmically spaced well outside binary
                    if app.N > 2
                        rMin   = sep * 3.5;    % safe minimum — inside this the binary destabilizes orbits
                        rMax   = sep * 10;
                        radiii  = logspace(log10(rMin), log10(rMax), app.N - 2);
                
                        for i = 3:app.N
                            r  = radiii(i - 2);
                            th = rand() * 2 * pi;
                            app.pos(i,:) = [r*cos(th), r*sin(th)];
                            % Effective central mass for circumbinary orbit is both stars combined
                            v = sqrt(app.G * 2 * mStar / r) * 0.95;
                            app.vel(i,:) = [-sin(th), cos(th)] * v;
                        end
                    end
                case 'Henon'
                    % Resonant hierarchical 3-body system creating the spirograph flower
                    setupGSdTTick(app, 1, 0, 0.005, 50, 0.7);
                    app.N = 3;
                    app.NumBodiesField.Value = 3;
                    
                    app.trailLen = 1500;
                    
                    app.mass = [1; 1; 1]; % 1 heavy outer body, 2 lighter inner bodies
                    app.pos = zeros(app.N, 2);
                    app.vel = zeros(app.N, 2);
                    
                    % --- Outer Orbit Mechanics ---
                    D = 20; % Distance between heavy body and inner binary barycenter
                    M_total = sum(app.mass);
                    w_outer = sqrt(app.G * M_total / (D^3));
                    
                    % Barycenter balances: r1 * M1 = r23 * (M2+M3)
                    r1 = -D / 2 ;
                    r23 = D / 2;
                    
                    v1 = w_outer * abs(r1);
                    v23 = w_outer * abs(r23);
                    
                    % Heavy Outer Body (Yellow)
                    app.pos(1,:) = [r1, 0];
                    app.vel(1,:) = [0, -v1];
                    
                    % --- Inner Binary Mechanics ---
                    d = 3; % Distance between the two smaller bodies
                    w_inner = sqrt(app.G * (app.mass(2) + app.mass(3)) / (d^3));
                    v_inner = w_inner * (d / 2);
                    
                    % First inner body (Red)
                    app.pos(2,:) = [r23 - d/2, 0];
                    app.vel(2,:) = [0, v23 + v_inner];
                    
                    % Second inner body (Teal)
                    app.pos(3,:) = [r23 + d/2, 0];
                    app.vel(3,:) = [0, v23 - v_inner];

                    totalM = sum(app.mass);
                    momentum = sum(app.mass .* app.vel, 1);
                    app.vel = app.vel - (momentum / totalM);

                case 'Lagrange'
                    % Exact Lagrange equilateral triangle configuration (3 equal masses)
                    setupGSdTTick(app, 1, 0.01, 0.02, 20, 0.5);
                    
                    app.N = 3;
                    app.NumBodiesField.Value = 3;
                    
                    app.mass = ones(3,1);                    
                    app.pos = zeros(3,2);
                    app.vel = zeros(3,2);
                    
                    % Radius of the triangle
                    R = 5;                                        
                    omega = 0.05; % Angular velocity
                    
                    % Generate equilateral triangle (120° apart)
                    for i = 1:3
                        theta = 2*pi*(i-1)/3;
                        
                        app.pos(i,1) = R*cos(theta);
                        app.pos(i,2) = R*sin(theta);                        

                        app.vel(i,1) = -omega * R * sin(theta);
                        app.vel(i,2) =  omega * R * cos(theta);
                    end                   
                case 'Hexagon 6'
                    % 6 equal bodies at vertices of a regular hexagon
                    setupGSdTTick(app, 1, 0, 0.002, 50, 0.5);
                
                    app.N = 6;
                    app.NumBodiesField.Value = 6;
                    app.mass = ones(6,1);
                
                    % Hexagon geometry
                    R = 10;
                    angles = (0:5) * 60;
                    angles_rad = deg2rad(angles);
                
                    app.pos = R * [cos(angles_rad)', sin(angles_rad)'];
                
                    % Velocity (tangential)
                    v_mag = sqrt(app.G * app.mass(1) / R);
                    v_mag = v_mag * 0.9;
                
                    app.vel = v_mag * [-sin(angles_rad)', cos(angles_rad)'];
                
                    % Remove center-of-mass drift
                    v_cm = sum(app.vel .* app.mass, 1) / sum(app.mass);
                    app.vel = app.vel - v_cm;
                case 'Octagon 8'
                    % 8 equal bodies at vertices of a regular octagon
                    setupGSdTTick(app, 1, 0, 0.002, 50, 0.5);
                
                    app.N = 8;
                    app.NumBodiesField.Value = 8;
                    app.mass = ones(8,1);
                
                    % Octagon geometry
                    R = 10;
                    angles = (0:7) * 45;
                    angles_rad = deg2rad(angles);
                
                    app.pos = R * [cos(angles_rad)', sin(angles_rad)'];
                
                    % Tangential velocity
                    v_mag = sqrt(app.G * app.mass(1) / R);
                    v_mag = v_mag * 0.9;   % tweak 0.85–0.95 for patterns
                
                    app.vel = v_mag * [-sin(angles_rad)', cos(angles_rad)'];
                
                    % Remove center-of-mass drift
                    v_cm = sum(app.vel .* app.mass, 1) / sum(app.mass);
                    app.vel = app.vel - v_cm;
               
                    app.trailLen = 900;
               case 'Decagon 10'
                    % 10 equal bodies at vertices of a regular decagon
                    setupGSdTTick(app, 1, 0, 0.002, 50, 0.5);
                
                    app.N = 10;
                    app.NumBodiesField.Value = 10;
                    app.mass = ones(10,1);
                
                    % Decagon geometry (36 degrees apart)
                    R = 10;
                    angles = (0:9) * 36;
                    angles_rad = deg2rad(angles);
                
                    app.pos = R * [cos(angles_rad)', sin(angles_rad)'];
                
                    % Tangential velocity
                    v_mag = sqrt(app.G * app.mass(1) / R);
                    v_mag = v_mag * 0.9;   % Tweak around 0.85-0.95 for different rosette patterns
                
                    app.vel = v_mag * [-sin(angles_rad)', cos(angles_rad)'];
                
                    % Remove center-of-mass drift
                    v_cm = sum(app.vel .* app.mass, 1) / sum(app.mass);
                    app.vel = app.vel - v_cm;
                
                    app.trailLen = 1000;
                case 'Pentagon 5'
                    % 5 equal bodies at vertices of a regular pentagon
                    setupGSdTTick(app, 1, 0, 0.002, 50, 0.5);
                
                    app.N = 5;
                    app.NumBodiesField.Value = 5;
                    app.mass = ones(5,1);
                
                    % Pentagon geometry (72 degrees apart)
                    R = 10;
                    angles = (0:4) * 72;
                    angles_rad = deg2rad(angles);
                
                    app.pos = R * [cos(angles_rad)', sin(angles_rad)'];
                
                    % Tangential velocity
                    v_mag = sqrt(app.G * app.mass(1) / R);
                    v_mag = v_mag * 0.9;   % Tweak around 0.85-0.95 for different rosette patterns
                
                    app.vel = v_mag * [-sin(angles_rad)', cos(angles_rad)'];
                
                    % Remove center-of-mass drift
                    v_cm = sum(app.vel .* app.mass, 1) / sum(app.mass);
                    app.vel = app.vel - v_cm;
                
                    app.trailLen = 1500;
                case 'Heptagon 7'
                    % 7 equal bodies at vertices of a regular heptagon
                    setupGSdTTick(app, 1, 0, 0.002, 50, 0.5);
                
                    app.N = 7;
                    app.NumBodiesField.Value = 7;
                    app.mass = ones(7,1);
                
                    % Heptagon geometry (approx 51.43 degrees apart)
                    R = 10;
                    angles = (0:6) * (360/7);
                    angles_rad = deg2rad(angles);
                
                    app.pos = R * [cos(angles_rad)', sin(angles_rad)'];
                
                    % Tangential velocity
                    v_mag = sqrt(app.G * app.mass(1) / R);
                    v_mag = v_mag * 0.9;   % Multiplier for the floral/rosette effect
                
                    app.vel = v_mag * [-sin(angles_rad)', cos(angles_rad)'];
                
                    % Remove center-of-mass drift
                    v_cm = sum(app.vel .* app.mass, 1) / sum(app.mass);
                    app.vel = app.vel - v_cm;
                
                    % Trail settings
                    app.trailLen = 950;
                case 'Nonagon 9'
                    % 9 equal bodies at vertices of a regular nonagon
                    setupGSdTTick(app, 1, 0, 0.002, 50, 0.5);
                
                    app.N = 9;
                    app.NumBodiesField.Value = 9;
                    app.mass = ones(9,1);
                
                    % Nonagon geometry (40 degrees apart)
                    R = 10;
                    angles = (0:8) * 40;
                    angles_rad = deg2rad(angles);
                
                    app.pos = R * [cos(angles_rad)', sin(angles_rad)'];
                
                    % Tangential velocity for the rosette effect
                    v_mag = sqrt(app.G * app.mass(1) / R);
                    v_mag = v_mag * 0.9; 
                
                    app.vel = v_mag * [-sin(angles_rad)', cos(angles_rad)'];
                
                    % Remove center-of-mass drift
                    v_cm = sum(app.vel .* app.mass, 1) / sum(app.mass);
                    app.vel = app.vel - v_cm;
                
                    % Trail settings
                    app.trailLen = 1000;
                case 'Butterfly'
                    % Periodic 3-body "Butterfly" orbit (equal masses)
                    setupGSdTTick(app, 1, 0.001, 0.001, 100, 0.5);
                    
                    app.N = 3;
                    app.NumBodiesField.Value = 3;
                    
                    % Equal masses
                    app.mass = ones(3, 1);
                    
                    % Initialize
                    app.pos = zeros(3, 2);
                    app.vel = zeros(3, 2);
                    
                    % Scaling factor (controls size of orbit)
                    scale = 4;
                    
                    % Canonical Butterfly I initial conditions
                    % (from Moore/Chenciner-Montgomery type families)
                    r1 = [ 1.0, 0.0];
                    r2 = [-1.0, 0.0];
                    r3 = [ 0.0, 0.0];
                    
                    v1 = [0.306893, 0.125507];
                    
                    % Scale positions
                    app.pos(1,:) =  scale * r1;
                    app.pos(2,:) =  scale * r2;
                    app.pos(3,:) =  scale * r3;
                    
                    % Velocity scaling (gravitational invariance)
                    vScale = 1 / sqrt(scale);
                    
                    % Assign velocities (ensure total momentum = 0)
                    app.vel(1,:) =  vScale * v1;
                    app.vel(2,:) =  vScale * v1;
                    app.vel(3,:) = -2 * vScale * v1;
                    
                    v_cm = mean(app.vel, 1);
                    app.vel = app.vel - v_cm;
                    
                    app.trailLen = 1500;
                case 'Broucke'
                    % Broucke periodic orbit (3‑body, equal masses) – scaled up version
                    setupGSdTTick(app, 1, 1e-4, 0.005, 80, 0.5);
                    
                    app.N = 3;
                    app.NumBodiesField.Value = 3;
                    app.mass = ones(3,1);
                    
                    scale = 3.0;
                    
                    % Base positions (from your screenshot, already centred)
                    pos_base = [
                        0.013260,   0;
                        1.415720,   0;
                       -1.428980,   0
                    ];
                    % Apply scaling
                    app.pos = scale * pos_base;
                    
                    % Base velocities (as given)
                    vel_base = [
                        0,   1.054151;
                        0,  -0.21014;
                        0,  -0.84400
                    ];
                    % Velocity scaling to preserve dynamics: v_new = v_old / sqrt(scale)
                    app.vel = vel_base / sqrt(scale);
                    
                    % Centre of mass remains at (0,0) because scaling is linear.
                    % Net momentum also remains zero.
                    
                    app.trailLen = 1500;
            end
            app.NumBodiesField.Value = app.N;

            if ~any(strcmp(preset, {'Figure8', 'Square4', 'Yarn', 'Henon', 'Lagrange', 'Hexagon-6', 'Octagon-8','Decagon-10','Pentagon-5','Heptagon-7','Nonagon-9','Butterfly','Broucke'}))
                comV = sum(app.mass .* app.vel, 1) / sum(app.mass);
                app.vel = app.vel - comV;
            end

            app.colors = app.softColors(app.N);
            app.radii = (5 + 12 * (app.mass / max(app.mass)).^(1/3)).^2;

            if app.TrailCheckBox.Value
                app.trailX = cell(app.N,1);
                app.trailY = cell(app.N,1);
                for i=1:app.N
                    app.trailX{i} = app.pos(i, 1);
                    app.trailY{i} = app.pos(i, 2);
                end
            end

            % collision body radius
            massNorm = (app.mass ./ max(app.mass)).^(1/3);
            app.bodyRadius = max(massNorm * app.radiusMultiplier, 0.7 * app.radiusMultiplier);            
            axis(app.SimAxes, 'equal');
        end

        function initGraphics(app)
            cla(app.SimAxes);
            delete(app.starPlot);
            hold(app.SimAxes, 'on');                        

            limit = app.resetCamera();
            app.lastStarLimit = limit;
            app.starPlot = app.spawnStars(limit);
            
            if app.TrailCheckBox.Value
                app.trailLines = gobjects(app.N,1);
                for i = 1:app.N
                    app.trailLines(i) = line(app.SimAxes, app.pos(i,1), app.pos(i,2), 'Color', [app.colors(i,:), 0.7], 'LineWidth', 1.2);
                end
            end

            app.bodyPlots = gobjects(app.N, 1);
            for i = 1:app.N
                r = app.bodyRadius(i);
                % Position format: [x_min, y_min, width, height]
                position = [app.pos(i,1)-r, app.pos(i,2)-r, 2 * r, 2 * r];

                app.bodyPlots(i) = rectangle(app.SimAxes, ...
                    'Position', position, ...
                    'Curvature', [1 1], ...
                    'FaceColor', app.colors(i,:), ...
                    'EdgeColor', 'none', ...
                    'HitTest', 'off');
            end

            hold(app.SimAxes, 'off');
        end

        function stepSimulation(app)
            m = app.mass;
            e2 = app.softening^2;
            a1 = nbodyAcc(app, app.pos, m, app.G, e2);
            vMid = app.vel + a1 * (app.dt/2);
            app.pos = app.pos + vMid * app.dt;
            a2 = nbodyAcc(app, app.pos, m, app.G, e2);
            app.vel = vMid + a2 * (app.dt/2);

            if app.CollisionCheckBox.Value
                app.resolveCollisions();
            end

            app.simTime = app.simTime + app.dt;
            app.stepCount = app.stepCount + 1;
        end

        function updateGraphics(app)
            if ~isvalid(app.NBodySimulator)
                return;
            end
            for i = 1:app.N
                if app.TrailCheckBox.Value
                    app.trailX{i}(end + 1) = app.pos(i, 1);
                    app.trailY{i}(end + 1) = app.pos(i, 2);
                    if numel(app.trailX{i}) > app.trailLen
                        app.trailX{i}(1) = [];
                        app.trailY{i}(1) = [];
                    end
                    set(app.trailLines(i), 'XData', app.trailX{i}, 'YData', app.trailY{i});
                end
            end
            
            % Update the physical position and size for every body
            for i = 1:app.N
                r = app.bodyRadius(i);
                app.bodyPlots(i).Position = [app.pos(i,1)-r, app.pos(i,2)-r, 2 * r, 2 * r];
            end

            if mod(app.stepCount, 10) == 0
                limit = app.resetCamera();

                % Regen stars
                if app.DecorationCheckBox.Value && (limit > app.lastStarLimit * 1.2)
                    delete(app.starPlot);                   
                    app.lastStarLimit = limit;
                   
                    hold(app.SimAxes, 'on');
                    app.starPlot = app.spawnStars(limit);
                    uistack(app.starPlot, 'bottom');
                    hold(app.SimAxes, 'off');
                    drawnow limitrate;
                end
            end

            drawnow limitrate;
        end

        % Application loop
        function timerFcn(app, ~, ~)
            if ~app.isRunning || ~isvalid(app.NBodySimulator)
                return;
            end

            %Calculate FPS
            currentTime = toc(app.lastFrameTime); 
            app.lastFrameTime = tic; % Reset for next frame
            currentFPS = 0;
            if currentTime > 0
                currentFPS = 1 / currentTime;
            end

            try
                for k = 1:app.stepsPerTick
                    app.stepSimulation();
                end
                app.updateGraphics();

                if mod(app.stepCount, 40) == 0
                    app.updateEnergyPlot();
                    app.Label.Text = sprintf('T = %.3f(Sim units)\nSteps = %d\nFPS: %.1f', app.simTime, app.stepCount, currentFPS);
                end

                if app.ExportSimCheckBox.Value && mod(app.stepCount, app.exportSnapTickInterval) == 0
                    %(Fuad) store positions to render after sim ends
                    %disp(app.exportSnapTickInterval);
                    snapshot.pos = app.pos;
                    snapshot.limit = max(max(abs(app.pos))) * 1.3 + 10;
                    if app.TrailCheckBox.Value
                        snapshot.trailX = app.trailX;
                        snapshot.trailY = app.trailY;
                    end
                    app.PosHistory{end + 1} = snapshot;                    
                end
            catch ME
                %fprintf('Error: %s\n', ME.message);
                %fprintf('In: %s line %d\n', ME.stack(1).name, ME.stack(1).line);
                % Print full stack
                %for i = 1:length(ME.stack)
                %    fprintf('  %s (line %d)\n', ME.stack(i).name, ME.stack(i).line);
                %end
                app.stopSim(false);                
            end
        end
        
        function startSim(app)
            if ~app.isInit
                app.initSimulation();
            end
            app.lastFrameTime = tic;
            app.isRunning = true;
            % The fix: only start if it's currently stopped
            if isvalid(app.simTimer) && strcmp(app.simTimer.Running, 'off')
                start(app.simTimer);
                app.PRESETLabel.Text = "Simulation is Running";
                app.PresetDropDown.Enable = "off";
                app.AutoConfigureCheckBox.Enable = "off";                
                app.GravSlider.Enable = "off";
                app.TimeStepSlider.Enable = "off";
                app.StepsPerTickSlider.Enable = "off";
                app.NumBodiesField.Enable = "off";
                app.CollisionCheckBox.Enable = "off";
                app.ElasticCheckBox.Enable = "off";
                app.DecorationCheckBox.Enable = "off";
                app.TrailCheckBox.Enable = "off";
                app.ExportSimCheckBox.Enable = "off";
                app.ExportFPSField.Enable = "off";
                app.ExportPresets.Enable = "off";
                drawnow;
            end
        end

        function stopSim(app, isPaused)
            
            if isvalid(app.simTimer) && strcmp(app.simTimer.Running, 'on')
                stop(app.simTimer);
            end

            if (isPaused == false)
                app.PRESETLabel.Text = "PRESET";
                app.PresetDropDown.Enable = "on";
                app.AutoConfigureCheckBox.Enable = "on";                
                app.GravSlider.Enable = "on";
                app.TimeStepSlider.Enable = "on";
                app.StepsPerTickSlider.Enable = "on";
                app.NumBodiesField.Enable = "on";
                app.CollisionCheckBox.Enable = "on";
                app.ElasticCheckBox.Enable = "on";
                app.DecorationCheckBox.Enable = "on";
                app.TrailCheckBox.Enable = "on";
                app.ExportSimCheckBox.Enable = "on";
                app.ExportFPSField.Enable = "on";
                app.ExportPresets.Enable = "on";
                drawnow;
            end

            if app.ExportSimCheckBox.Value && app.isRunning && isPaused == false
                app.renderAndExportVideo();
            end

            app.isRunning = false;
        end        
        
        function acc = nbodyAcc(~, pos, m, G, e2)
             numBodies = size(pos,1);
             dx = pos(:,1)' - pos(:,1); 
             dy = pos(:,2)' - pos(:,2);
             r2 = dx.^2 + dy.^2 + e2;
             invR3 = r2.^(-1.5);
             invR3(1:numBodies + 1:end) = 0; 
             fac = G * (m') .* invR3;
             acc = [sum(fac.*dx, 2), sum(fac.*dy, 2)];
        end        
        
        function setupGSdTTick(app, G, softening, dt, stepsPerTick, rMul)
            app.softening = softening;
            app.StepsPerTickSlider.Value = app.softening;

            app.GravSlider.Enable = "on";
            app.TimeStepSlider.Enable = "on";
            app.StepsPerTickSlider.Enable = "on";
            app.RadiusMultplierSlider.Enable = "on";
            app.InfoLabel.Text = "Auto-Configure preset properties is off, changing proprety values may not be visually appealing for certain presets";

            if (app.AutoConfigureCheckBox.Value)                
                app.G = G;
                app.GravSlider.Value = app.G;
                app.GravSlider.Enable = "off";
    
                app.dt = dt; 
                app.TimeStepSlider.Value = app.dt;
                app.TimeStepSlider.Enable = "off";

                app.stepsPerTick = stepsPerTick;
                app.StepsPerTickSlider.Value = app.stepsPerTick;
                app.StepsPerTickSlider.Enable = "off";

                app.radiusMultiplier = rMul;
                app.RadiusMultplierSlider.Value = app.radiusMultiplier;
                app.RadiusMultplierSlider.Enable = "off";

                app.InfoLabel.Text = "Uncheck 'Auto-Configure' to edit properties";
            end
            drawnow;
        end
        
        function initEnergyPlot(app)
            cla(app.EnrgAxes);
            hold(app.EnrgAxes, 'on');
            app.KELine = line(app.EnrgAxes, NaN, NaN, 'Color', [1.0 0.4 0.4], 'LineWidth', 1.2);
            app.PELine = line(app.EnrgAxes, NaN, NaN, 'Color', [0.4 0.6 1.0], 'LineWidth', 1.2);
            app.TELine = line(app.EnrgAxes, NaN, NaN, 'Color', [0.4 1.0 0.4], 'LineWidth', 2);
            legend(app.EnrgAxes, {'KE','PE','Total'}, 'TextColor', [1 1 1], 'Color', [0.1 0.1 0.1], 'EdgeColor', [0.3 0.3 0.3], 'Location', 'northwest', 'FontSize', 9);
            hold(app.EnrgAxes, 'off');
        end

        function [ke, pe, te] = computeEnergy(app)
            % Kinetic energy
            ke = 0.5 * sum(app.mass .* sum(app.vel.^2, 2));

            % Potential energy
            pe = 0;
            for i = 1:app.N
                for j = i+1:app.N
                    dx  = app.pos(j,1) - app.pos(i,1);
                    dy  = app.pos(j,2) - app.pos(i,2);
                    r   = sqrt(dx^2 + dy^2 + app.softening^2);
                    pe  = pe - app.G * app.mass(i) * app.mass(j) / r;
                end
            end
        
            te = ke + pe;
        end
        
        function updateEnergyPlot(app)
            [ke, pe, te] = app.computeEnergy();        
            app.timeHistory(end + 1) = app.simTime;
            
            newRow = [ke, pe, te];
            app.energyHistory = [app.energyHistory; newRow];
        
            maxPoints = 200;
            if numel(app.timeHistory) > maxPoints %clear
                app.timeHistory(1) = [];
                app.energyHistory(1,:) = [];
            end
        
            % Guard: only update plot if sizes match
            n = numel(app.timeHistory);
            if size(app.energyHistory, 1) ~= n
                app.timeHistory = [];
                app.energyHistory = zeros(0, 3);
                return;
            end
        
            if n < 2
                return; % Not enough points to draw a line
            end

            set(app.KELine, 'XData', app.timeHistory, 'YData', app.energyHistory(:,1)');
            set(app.PELine, 'XData', app.timeHistory, 'YData', app.energyHistory(:,2)');
            set(app.TELine, 'XData', app.timeHistory, 'YData', app.energyHistory(:,3)');
        
            axis(app.EnrgAxes, 'auto');
            drawnow limitrate;
        end

        % Arefin
        function resolveCollisions(app)            
            if app.ElasticCheckBox.Value == 1
                e = 1;
            else
                e = 0.4;
            end

            for i = 1:app.N-1
                for j = i+1:app.N
                    % Distance check
                    dp = app.pos(i,:) - app.pos(j,:);
                    dist = norm(dp);
                    minDist = app.bodyRadius(i) + app.bodyRadius(j);
                    
                    if dist < minDist
                        % Normal vector
                        n = dp / dist;

                        vRel = app.vel(i,:) - app.vel(j,:);                        
                        vNorm = dot(vRel, n);
                        
                        % Only resolve if they are moving TOWARD each other
                        if vNorm < 0                           
                            % Impulse scalar (Elastic collision formula)
                            % J = 2 * (v1 - v2) . n / (1/m1 + 1/m2)
                            impulse = ((1+e) * vNorm) / (1/app.mass(i) + 1/app.mass(j));
                            
                            % 5. Apply impulse to velocities
                            app.vel(i,:) = app.vel(i,:) - (impulse / app.mass(i)) * n;
                            app.vel(j,:) = app.vel(j,:) + (impulse / app.mass(j)) * n;
                            
                            % 6. Static resolution (prevent sticking)
                            overlap = minDist - dist;
                            app.pos(i,:) = app.pos(i,:) + n * (overlap * (app.mass(j)/(app.mass(i)+app.mass(j))));
                            app.pos(j,:) = app.pos(j,:) - n * (overlap * (app.mass(i)/(app.mass(i)+app.mass(j))));
                        end
                    end
                end
            end   
        end
        
        function setPresetDescription(app, preset)
            app.PresetTitle.Text = string(preset);

            switch preset        
                case 'Figure 8'
                    app.PresetDesc.Text = ...
                    "The figure-eight orbit is a rare exact periodic solution of the three-body problem, discovered numerically and later proven analytically. It shows that even in a system governed by nonlinear inverse-square forces, highly ordered and repeating motion can exist. This challenges the common assumption that three-body motion is always chaotic." + newline + newline + ...
                    "Three identical masses are placed in a symmetric configuration such that all bodies follow the same path with a phase offset. The initial conditions are derived from canonical values and scaled, while velocities are adjusted using inverse square-root scaling to preserve gravitational similarity under spatial transformation." + newline + newline + ...
                    "The simulator computes pairwise gravitational forces using an O(N^2) formulation and integrates motion using a midpoint scheme. Acceleration is evaluated twice per step to reduce energy drift. A very small timestep is required because the orbit depends on precise phase alignment, and even small numerical errors can destroy periodicity over time.";        
                case 'Yarn'
                    app.PresetDesc.Text = ...
                    "This configuration represents a choreographic solution where multiple bodies share intertwined trajectories, forming a woven structure. Such solutions arise from resonance conditions in nonlinear dynamical systems, where gravitational interactions synchronize motion." + newline + newline + ...
                    "Two bodies are initialized with identical velocities while the third compensates to ensure total momentum is zero. The velocity scaling depends on gravitational strength and spatial scaling, preserving the qualitative structure of the orbit." + newline + newline + ...
                    "The integrator uses a semi-implicit midpoint update, which improves stability over simple Euler integration. The system maintains coherence because the resonance condition keeps energy distributed in a structured way rather than dispersing chaotically.";
        
                case 'Random Cluster'
                    app.PresetDesc.Text = ...
                    "This setup models gravitational N-body collapse, similar to early star cluster or galaxy formation scenarios studied in computational astrophysics. It demonstrates how large-scale structure can emerge from initially random conditions through gravitational attraction alone." + newline + newline + ...
                    "Bodies are randomly distributed within a finite region, and velocities are scaled below the virial threshold so that the system tends toward collapse rather than expansion. This mimics sub-virial initial conditions often used in simulations of self-gravitating systems." + newline + newline + ...
                    "A relatively large softening parameter is used to prevent singular accelerations during close encounters, effectively smoothing the gravitational potential at short distances. The simulation evolves through repeated pairwise interactions, producing clustering, scattering, and energy exchange.";        
                case 'Solar System'
                    app.PresetDesc.Text = ...
                    "This configuration reflects classical celestial mechanics developed from Kepler’s observational laws and Newton’s gravitational theory. It demonstrates how orbital velocity depends on radial distance and how stable orbits arise from the balance between centripetal acceleration and gravitational attraction." + newline + newline + ...
                    "A dominant central mass represents a star, while smaller bodies are placed at increasing radii with velocities computed from the circular orbit condition v = sqrt(GM/r). Random angular placement ensures a more natural distribution." + newline + newline + ...
                    "Although all bodies interact gravitationally, the central mass dominates the force field, making most orbits approximately Keplerian. The numerical integrator approximates continuous motion and introduces small deviations over time due to accumulated numerical error.";        
                case 'Binary System'
                    app.PresetDesc.Text = ...
                    "Binary systems are a fundamental outcome of gravitational collapse in astrophysics. Two massive bodies orbit a shared barycenter, creating a time-dependent gravitational field that influences surrounding objects." + newline + newline + ...
                    "The two central masses are initialized with equal and opposite velocities to ensure stable orbital motion around their center of mass. Additional bodies are placed at larger radii with velocities corresponding to the combined gravitational influence." + newline + newline + ...
                    "The simulation naturally produces a moving gravitational potential. Outer bodies experience non-uniform forces as the barycenter shifts, resulting in complex but bounded trajectories that reflect real circumbinary systems.";        
                case 'Henon'
                    app.PresetDesc.Text = ...
                    "This configuration reflects hierarchical three-body systems studied by Michel Hénon, where motion separates into fast inner dynamics and slower outer motion. Such systems are important in understanding long-term stability in stellar systems." + newline + newline + ...
                    "A tight inner binary orbits rapidly while their combined center of mass orbits a third body. Angular velocities are computed using gravitational scaling laws to maintain resonance between the two motions." + newline + newline + ...
                    "The simulator must resolve two different timescales simultaneously. The midpoint integration scheme allows stable evolution by balancing accuracy and computational cost while capturing both inner and outer orbital dynamics.";        
                case 'Butterfly'
                    app.PresetDesc.Text = ...
                    "The butterfly orbit is part of a family of periodic three-body solutions discovered through numerical experimentation. These solutions reveal hidden structure in systems that are otherwise highly sensitive to initial conditions." + newline + newline + ...
                    "Bodies are initialized symmetrically with velocities chosen to enforce zero net momentum and bounded motion. Scaling is applied to adjust orbit size while preserving the underlying dynamics." + newline + newline + ...
                    "The system is highly sensitive to perturbations. Numerical integration must maintain symmetry to prevent divergence. Even small timestep changes can lead to long-term instability due to accumulated phase error.";        
                case 'Broucke'
                    app.PresetDesc.Text = ...
                    "Broucke’s periodic solutions demonstrate that bodies can undergo repeated close encounters without collision, highlighting the delicate balance possible in gravitational systems." + newline + newline + ...
                    "Bodies are initialized along a line with perpendicular velocities chosen to produce repeating motion. Scaling expands the orbit while maintaining its structure." + newline + newline + ...
                    "Close approaches create large accelerations, requiring stable numerical integration. The midpoint method reduces energy drift and helps preserve the periodic nature of the orbit.";        
                case 'Lagrange'
                    app.PresetDesc.Text = ...
                    "This is one of the classical solutions discovered by Lagrange, where three equal masses form an equilateral triangle that rotates rigidly. It represents a central configuration where forces are proportional to position vectors." + newline + newline + ...
                    "Bodies are placed symmetrically around a circle and given tangential velocities corresponding to uniform angular rotation." + newline + newline + ...
                    "Because the configuration is self-similar, the system remains stable under ideal conditions. Numerical integration preserves this structure by consistently applying gravitational forces and updating velocities in a time-centered manner.";        
                case 'Square 4'
                    app.PresetDesc.Text = ...
                    "Four-body symmetric configurations extend ideas from central configuration theory, but are generally less stable than triangular systems. Small perturbations tend to grow over time." + newline + newline + ...
                    "Bodies are placed at the corners of a square with velocities perpendicular to the radial direction, approximating rotational motion." + newline + newline + ...
                    "The simulation initially preserves symmetry, but numerical errors accumulate and eventually distort the configuration. The integration method delays this breakdown by improving energy consistency.";        
                case {'Pentagon 5','Hexagon 6','Heptagon 7','Octagon 8','Nonagon 9','Decagon 10'}
                    app.PresetDesc.Text = ...
                    "Regular polygon configurations relate to ring systems and collective gravitational motion. Exact circular motion requires precise velocity matching, which is intentionally offset here." + newline + newline + ...
                    "Bodies are placed uniformly on a circle and given slightly reduced tangential velocity compared to the circular orbit condition." + newline + newline + ...
                    "This velocity mismatch causes bodies to fall inward and then swing outward, producing repeating rosette patterns. The simulator captures this behavior through continuous force evaluation and time-stepped integration, revealing complex structure emerging from simple initial symmetry.";
                otherwise
                    app.PresetDesc.Text = "No description available.";
            end
        end
        
        % Color Functions(Fuad)
        function rgbColor = colorToNormalizedRGB(~, color)
            rgbColor = color ./ 255;
        end

        function colors = softColors(~, N)
            % Source(Fuad): https://www.geeksforgeeks.org/computer-graphics/hsv-color-model-in-computer-graphics/
            hues = (0:N - 1)' / N;
            saturation = ones(N, 1) * 0.70;   % 0 = white, 1 = fully saturated
            brightness = ones(N, 1) * 0.90;   % keep bright but not neon
            
            colors = hsv2rgb([hues, saturation, brightness]);
        end
        
        % Stars
        function h = spawnStars(app, limit)
            if app.DecorationCheckBox.Value == 0
                h = [];
                return;
            end

            starCount = 500;
            % Vary star sizes
            sizes  = 1 + rand(starCount,1) * 3;
            % Vary brightness slightly
            bright = 0.25 + rand(starCount,1) * 0.2;
            sColors = [bright, bright, bright + 0.15];  % Bluish tint
            sColors = min(sColors, 1);
            sPos = (rand(starCount, 2) - 0.5) * 2 * limit;
            h = scatter(app.SimAxes, sPos(:,1), sPos(:,2), sizes, sColors, 'filled', 'HandleVisibility', 'off');
        end

        
        function limit = resetCamera(app)
            limit = max(max(abs(app.pos))) * 1.3 + 10;
            axis(app.SimAxes, [-limit limit -limit limit]);
        end
        
        function initCollision(app)
            if app.ElasticCheckBox.Value
                app.ElasticCollisionInfo.Text = "Energy is conserved across collisions, the green line(total energy) in energy visualizer stays same";
            else
                app.ElasticCollisionInfo.Text = "Energy is NOT conserved across collisions, the green line(total energy) in energy visualizer changes";
            end

            if app.CollisionCheckBox.Value 
                app.ElasticCheckBox.Enable = "on";
            else
                app.ElasticCheckBox.Enable = "off";
                app.ElasticCollisionInfo.Text = "Elasticity determines energy conservation";
            end
        end
        
        function playButtonPressedSound(app)
            if isplaying(app.ButtonAudioPlayer)
                stop(app.ButtonAudioPlayer);
            end
            play(app.ButtonAudioPlayer);
            drawnow update;
        end
        
        function showWelcomeScreen(app)            
            app.EnrgInfoLayout.Visible = "off";
            app.SettingsLayout.Visible = "off";
            app.SimAxes.Visible = "off";
            drawnow;
            app.WelcomeScreenLayout.Visible = "on";
        end
        
        function keyHandler(app, event)
            if ~app.welcomeScreenHidden                
                app.hideWelcomeScreen()
            end

            if strcmp(event.Key, 'escape')
                app.isCancellingRender = true;
            end
        end
        function mouseDown(app)
            if ~app.welcomeScreenHidden                
               app.hideWelcomeScreen()
            end                 
        end
        function hideWelcomeScreen(app)
            app.EnrgInfoLayout.Visible = "on";

            app.SettingsLayout.Visible = "on";
            app.SimAxes.Visible = "on";

            delete(app.WelcomeScreenLayout);    
            app.NBodySimulator.WindowState = 'maximized';
            app.welcomeScreenHidden = 1;

            app.NBodySimulator.WindowButtonDownFcn = [];
        end
        
        function renderAndExportVideo(app)
            app.isRenderingVideo = true;
            app.isCancellingRender = false;
            filename = sprintf('NBodySim_%s.mp4', datetime('now', 'Format', 'yyyyMMdd_HHmmss'));
            writer = VideoWriter(filename, 'MPEG-4');
            writer.FrameRate = app.ExportFPSField.Value;
            open(writer);
            
            drawnow;
            app.SettingsLayout.Visible = 'off';
            app.EnrgInfoLayout.Visible = 'off';
            app.SimAxes.Subtitle.String = 'Starting video export...';
            drawnow;
        
            % Collect fixed size from first frame
            snap = app.PosHistory{1};

            for i = 1:app.N
                r = app.bodyRadius(i);
                app.bodyPlots(i).Position = [snap.pos(i,1)-r, snap.pos(i,2)-r, 2 * r, 2 * r];
            end

            if app.TrailCheckBox.Value && isfield(snap, 'trailX')
                for i = 1:app.N
                    set(app.trailLines(i), 'XData', snap.trailX{i}, 'YData', snap.trailY{i});
                end
            end

            if isfield(snap, 'limit')
                limit = snap.limit;
                axis(app.SimAxes, [-limit limit -limit limit]);
            end
            drawnow;

            f = getframe(app.SimAxes);
            targetSize = [size(f.cdata, 1), size(f.cdata, 2)];
            writeVideo(writer, f);
        
            focus(app.NBodySimulator);

            renderStartTime = datetime('now');
            framesRendered  = 0;

            for k = 2:numel(app.PosHistory)
                if app.isCancellingRender || ~isvalid(app.NBodySimulator)
                    break;
                end

                snap = app.PosHistory{k};
                for i = 1:app.N
                    r = app.bodyRadius(i);
                    app.bodyPlots(i).Position = [snap.pos(i,1)-r, snap.pos(i,2)-r, 2 * r, 2 * r];
                end

                
                if app.TrailCheckBox.Value && isfield(snap, 'trailX')
                    for i = 1:app.N
                        set(app.trailLines(i), 'XData', snap.trailX{i}, 'YData', snap.trailY{i});
                    end
                end

                if isfield(snap, 'limit')
                    limit = snap.limit;
                    axis(app.SimAxes, [-limit limit -limit limit]);
                end
                drawnow;

                f = getframe(app.SimAxes);
                if ~isequal([size(f.cdata,1) size(f.cdata,2)], targetSize)
                    f.cdata = imresize(f.cdata, targetSize);
                end
                writeVideo(writer, f);

                % Calculation of estimated time
                framesRendered = framesRendered + 1;
                totalElapsed = seconds(datetime('now') - renderStartTime);
                avgTimePerFrame = totalElapsed / framesRendered;
                framesRemaining = numel(app.PosHistory) - k;
                etaSeconds = avgTimePerFrame * framesRemaining;
            
                if etaSeconds >= 60
                    etaStr = sprintf('%dm %ds', floor(etaSeconds / 60), mod(round(etaSeconds), 60));
                else
                    etaStr = sprintf('%ds', round(etaSeconds));
                end

                percentage = (k/numel(app.PosHistory)) * 100;
                if app.isCancellingRender
                    app.SimAxes.Subtitle.String = 'Render cancelled, saving incomplete video..';
                else
                    app.SimAxes.Subtitle.String = sprintf(...
                    'Rendering video\nFrame: %d/%d // Progress: %.2f%%\nEstimated time: %s\nPress ESC to cancel rendering', ...
                    k, numel(app.PosHistory), percentage, etaStr);
                end
            end

            appDir = app.getCurrentWorkingDirectory();

            app.ExportInfo.Text = sprintf("Video exported successfully to: %s/%s", appDir, filename);
            close(writer);
            app.SimAxes.Subtitle.String = app.ExportInfo.Text;
            pause(1);
            app.SettingsLayout.Visible = 'on';
            app.EnrgInfoLayout.Visible = 'on';
            app.SimAxes.Subtitle.String = "";
            app.ExportSimCheckBox.Value = 0;
            app.isRenderingVideo = false;
            drawnow;
        end
        
        function tickInterval = exportQualityToSettings(~, exportQuality)
            switch exportQuality
                case 'Excellent'
                    tickInterval = 10;
                case 'High'
                    tickInterval = 35;
                case 'Normal'
                    tickInterval = 50;
                case 'Low'
                    tickInterval = 100;
                case 'Very Low'
                    tickInterval = 300;
            end
        end
        
        function appDir = getCurrentWorkingDirectory(~)
            % (Fuad)From: https://www.mathworks.com/matlabcentral/answers/146368-how-can-i-get-the-folder-where-the-standalone-application-exe-is-installed-in-order-to-access-add#comment_3341083
            if (isdeployed())
                % Use .NET to find the name of the .exe
                appDir = System.Diagnostics.Process.GetCurrentProcess();
                appDir = char(appDir.MainModule.FileName);
                appDir = fileparts(appDir);
            else
                % Use path of .mlapp file if not compiled
                appDir = mfilename('fullpath');
                appDir = fileparts(appDir);
            end
        end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.NBodySimulator, 'center'); 

            app.welcomeScreenHidden = 0;
            app.NBodySimulator.KeyPressFcn = @(src,event) keyHandler(app, event);
            app.NBodySimulator.WindowButtonDownFcn = @(src,event) mouseDown(app);
            app.showWelcomeScreen();            
            
            % (Fuad) Cannot set these in the AppDesigner, thus setting these colors manually here 
            app.SimAxes.Title.Color = [1 1 1]; 
            app.SimAxes.Subtitle.Color = [1 1 1];
            app.EnrgAxes.Title.Color = [1 1 1];
            app.SimAxes.Subtitle.FontName = 'Artifakt Element Medium';

            appDir = app.getCurrentWorkingDirectory();
            audioDir = sprintf("%s/Assets/ButtonPressed.mp3", appDir);
            disp(audioDir);
            [y, f] = audioread(audioDir);
            app.ButtonAudioPlayer = audioplayer(y, f);

            app.simTimer = timer('ExecutionMode', 'fixedRate', 'Period', 0.033, 'TimerFcn', @app.timerFcn, 'BusyMode', 'drop');
            app.StopButton.Enable = "off";
            
            app.exportSnapTickInterval = app.exportQualityToSettings(app.ExportPresets.Value);

            app.initSimulation();
        end

        % Close request function: NBodySimulator
        function NBodySimulatorCloseRequest(app, event)
            app.stopSim(false);
            if (app.isRenderingVideo == true)
                app.isCancellingRender = true;
                timeout = datetime('now') + seconds(10);
                while app.isRenderingVideo && datetime('now') < timeout
                    pause(0.1);
                    drawnow;
                end
            end

            if isvalid(app.simTimer)
                delete(app.simTimer);
            end
            delete(app);
        end

        % Value changed function: AutoConfigureCheckBox, 
        % ...and 10 other components
        function OnResetPressed(app, event)
            if app.PPButton.Value == 1
                app.PPButton.Value = 0;
                OnPPPressed(app, event);
            end

            app.initSimulation();
        end

        % Value changed function: PPButton
        function OnPPPressed(app, event)
            value = app.PPButton.Value;
            if value == 1 % VALUE 1 means sim is stopped, start the simulation
                app.startSim();
                app.PPButton.Text = "PAUSE";
                app.PPButton.BackgroundColor = colorToNormalizedRGB(app, [255, 177, 61]);
                app.StopButton.Enable = "on"; % Only enable stop button when sim is playing
            else % VALUE 0 means sim is currently running, pause the simulation
                app.stopSim(true);
                app.PPButton.Text = "RESUME";
                app.PPButton.BackgroundColor = colorToNormalizedRGB(app, [0, 230, 88]);
            end

        end

        % Callback function: NBodySimulator, StopButton
        function OnSTOPPressed(app, event)
            app.stopSim(false);
            app.PPButton.Text = "RUN";
            app.PPButton.BackgroundColor = colorToNormalizedRGB(app, [0, 230, 88]);
            app.initSimulation();
            app.StopButton.Enable = "off";
            app.playButtonPressedSound();
        end

        % Value changed function: ExportSimCheckBox
        function ExportSimCheckBoxValueChanged(app, event)
            value = app.ExportSimCheckBox.Value;
            if value
                app.ExportInfo.Text = "Exporting longer simulations can take a while, exporting will start automatically when a 'running' simulation is stopped";
            else
                app.ExportInfo.Text = "Render & export the simulation as .mp4 file";
            end
            drawnow;            
        end

        % Value changed function: ExportPresets
        function ExportPresetsValueChanged(app, event)
            value = app.ExportPresets.Value;
            app.exportSnapTickInterval = app.exportQualityToSettings(value);
            %disp(app.exportSnapTickInterval);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NBodySimulator = uifigure('Visible', 'off');
            app.NBodySimulator.Name = 'N-Body Simulator';
            app.NBodySimulator.CloseRequestFcn = createCallbackFcn(app, @NBodySimulatorCloseRequest, true);
            app.NBodySimulator.ButtonDownFcn = createCallbackFcn(app, @OnSTOPPressed, true);
            app.MainLayout = uigridlayout(app.NBodySimulator);
            app.SimAxes = uiaxes(app.MainLayout);
            title(app.SimAxes, 'N-Body Simulation')
            app.SimAxes.XTick = [];
            app.SimAxes.XTickLabel = '';
            app.SimAxes.YTick = [];
            app.SimAxes.ZTick = [];
            colormap(app.SimAxes, 'hot')
            app.EnrgInfoLayout = uigridlayout(app.MainLayout);
            app.EnergyLayout = uigridlayout(app.EnrgInfoLayout);
            app.EnrgAxes = uiaxes(app.EnergyLayout);
            title(app.EnrgAxes, 'Energy Visualizer')
            app.EnrgAxes.XTick = [];
            app.EnrgAxes.YTick = [];
            app.EnrgAxes.ZTick = [];
            colormap(app.EnrgAxes, 'hot')
            app.DescriptionLayout = uigridlayout(app.EnrgInfoLayout);
            app.PresetTitle = uilabel(app.DescriptionLayout);
            app.PresetTitle.Text = 'Preset Title';
            app.PresetDesc = uilabel(app.DescriptionLayout);
            app.PresetDesc.Text = {'Lorem ipsum dolor sit amet consectetur adipiscing elit. Quisque faucibus ex sapien vitae pellentesque sem placerat. In id cursus mi pretium tellus duis convallis. Tempus leo eu aenean sed diam urna tempor. Pulvinar vivamus fringilla lacus nec metus bibendum egestas. Iaculis massa nisl malesuada lacinia integer nunc posuere. Ut hendrerit semper vel class aptent taciti sociosqu. Ad litora torquent per conubia nostra inceptos himenaeos.'; ''; 'Lorem ipsum dolor sit amet consectetur adipiscing elit. Quisque faucibus ex'};
            app.InfoLayout = uigridlayout(app.EnrgInfoLayout);
            app.Label = uilabel(app.InfoLayout);
            app.Label.Text = '';
            app.WelcomeScreenLayout = uigridlayout(app.MainLayout);
            app.WelcomeScreenLayout.Visible = 'off';
            app.Image = uiimage(app.WelcomeScreenLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Assets', 'NBSWelcome_Page.png');
            app.SettingsLayout = uigridlayout(app.MainLayout);
            app.PRESETLabel = uilabel(app.SettingsLayout);
            app.PRESETLabel.Text = 'PRESET';
            app.PresetDropDown = uidropdown(app.SettingsLayout);
            app.PresetDropDown.Items = {'Figure 8', 'Yarn', 'Random Cluster', 'Solar System', 'Binary System', 'Henon', 'Butterfly', 'Broucke', 'Lagrange', 'Square 4', 'Pentagon 5', 'Hexagon 6', 'Heptagon 7', 'Octagon 8', 'Nonagon 9', 'Decagon 10'};
            app.PresetDropDown.ValueChangedFcn = createCallbackFcn(app, @OnResetPressed, true);
            app.PresetDropDown.Value = 'Decagon 10';
            app.AutoConfigureCheckBox = uicheckbox(app.SettingsLayout);
            app.AutoConfigureCheckBox.ValueChangedFcn = createCallbackFcn(app, @OnResetPressed, true);
            app.AutoConfigureCheckBox.Tooltip = {'Automatically set the proper value of the preset properties so that it looks good'};
            app.AutoConfigureCheckBox.Text = 'Auto-Configure';
            app.AutoConfigureCheckBox.Value = true;
            app.GravitationalConstantGLabel = uilabel(app.SettingsLayout);
            app.GravitationalConstantGLabel.Text = 'Gravitational Constant (G):';
            app.GravSlider = uislider(app.SettingsLayout);
            app.GravSlider.Limits = [0.5 5];
            app.GravSlider.MajorTicks = [0.5 1 2 3 4 5];
            app.GravSlider.ValueChangedFcn = createCallbackFcn(app, @OnResetPressed, true);
            app.GravSlider.MinorTicks = [];
            app.GravSlider.Enable = 'off';
            app.GravSlider.Value = 1;
            app.TimestepdtLabel = uilabel(app.SettingsLayout);
            app.TimestepdtLabel.Text = 'Time step (dt):';
            app.TimeStepSlider = uislider(app.SettingsLayout);
            app.TimeStepSlider.Limits = [0.0001 0.1];
            app.TimeStepSlider.MajorTicks = [0.0001 0.03 0.058 0.1];
            app.TimeStepSlider.ValueChangedFcn = createCallbackFcn(app, @OnResetPressed, true);
            app.TimeStepSlider.MinorTicks = [];
            app.TimeStepSlider.Enable = 'off';
            app.TimeStepSlider.Value = 0.0001;
            app.StepspertickLabel = uilabel(app.SettingsLayout);
            app.StepspertickLabel.Text = 'Steps per tick:';
            app.StepsPerTickSlider = uislider(app.SettingsLayout);
            app.StepsPerTickSlider.Limits = [0 200];
            app.StepsPerTickSlider.MajorTicks = [0 20 40 60 80 100 120 140 160 180 200];
            app.StepsPerTickSlider.ValueChangedFcn = createCallbackFcn(app, @OnResetPressed, true);
            app.StepsPerTickSlider.MinorTicks = [];
            app.StepsPerTickSlider.Enable = 'off';
            app.StepsPerTickSlider.Value = 20;
            app.COLLISIONLabel = uilabel(app.SettingsLayout);
            app.COLLISIONLabel.Text = 'COLLISION';
            app.ElasticCollisionInfo = uilabel(app.SettingsLayout);
            app.ElasticCollisionInfo.Text = 'Elasticity determines energy conservation';
            app.DECORATIONLabel = uilabel(app.SettingsLayout);
            app.DECORATIONLabel.Text = 'DECORATION';
            app.EXPORTLabel = uilabel(app.SettingsLayout);
            app.EXPORTLabel.Text = 'EXPORT';
            app.ExportInfo = uilabel(app.SettingsLayout);
            app.ExportInfo.Text = 'Export the simulation as .mp4 file';
            app.PPButton = uibutton(app.SettingsLayout, 'state');
            app.PPButton.ValueChangedFcn = createCallbackFcn(app, @OnPPPressed, true);
            app.PPButton.Text = 'RUN';
            app.StopButton = uibutton(app.SettingsLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @OnSTOPPressed, true);
            app.StopButton.Text = 'STOP';
            app.InfoLabel = uilabel(app.SettingsLayout);
            app.InfoLabel.Text = 'Uncheck ''Auto-Configure'' to edit properties';
            app.ExportSimCheckBox = uicheckbox(app.SettingsLayout);
            app.ExportSimCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExportSimCheckBoxValueChanged, true);
            app.ExportSimCheckBox.Text = 'Render & export simulation';
            app.RadiusmultiplierLabel = uilabel(app.SettingsLayout);
            app.RadiusmultiplierLabel.Text = 'Radius multiplier';
            app.RadiusMultplierSlider = uislider(app.SettingsLayout);
            app.RadiusMultplierSlider.Limits = [0 5];
            app.RadiusMultplierSlider.ValueChangedFcn = createCallbackFcn(app, @OnResetPressed, true);
            app.RadiusMultplierSlider.Enable = 'off';
            app.RadiusMultplierSlider.Value = 1;
            app.ExportLayout = uigridlayout(app.SettingsLayout);
            app.FPSLabel = uilabel(app.ExportLayout);
            app.FPSLabel.Text = 'FPS:';
            app.ExportFPSField = uispinner(app.ExportLayout);
            app.ExportFPSField.Step = 5;
            app.ExportFPSField.Limits = [20 60];
            app.ExportFPSField.ValueDisplayFormat = '%.0f';
            app.ExportFPSField.Tooltip = {''};
            app.ExportFPSField.Value = 30;
            app.QualityLabel = uilabel(app.ExportLayout);
            app.QualityLabel.Text = 'Quality:';
            app.ExportPresets = uidropdown(app.ExportLayout);
            app.ExportPresets.Items = {'Excellent', 'High', 'Normal', 'Low', 'Very Low'};
            app.ExportPresets.ValueChangedFcn = createCallbackFcn(app, @ExportPresetsValueChanged, true);
            app.ExportPresets.Value = 'Normal';
            app.CollisionLayout = uigridlayout(app.SettingsLayout);
            app.CollisionCheckBox = uicheckbox(app.CollisionLayout);
            app.CollisionCheckBox.ValueChangedFcn = createCallbackFcn(app, @OnResetPressed, true);
            app.CollisionCheckBox.Text = 'Enable';
            app.ElasticCheckBox = uicheckbox(app.CollisionLayout);
            app.ElasticCheckBox.ValueChangedFcn = createCallbackFcn(app, @OnResetPressed, true);
            app.ElasticCheckBox.Text = 'Elastic';
            app.ElasticCheckBox.Value = true;
            app.DecorationLayout = uigridlayout(app.SettingsLayout);
            app.DecorationCheckBox = uicheckbox(app.DecorationLayout);
            app.DecorationCheckBox.ValueChangedFcn = createCallbackFcn(app, @OnResetPressed, true);
            app.DecorationCheckBox.Text = 'Stars';
            app.DecorationCheckBox.Value = true;
            app.TrailCheckBox = uicheckbox(app.DecorationLayout);
            app.TrailCheckBox.ValueChangedFcn = createCallbackFcn(app, @OnResetPressed, true);
            app.TrailCheckBox.Text = 'Trails';
            app.TrailCheckBox.Value = true;
            app.NumBodiesLayout = uigridlayout(app.SettingsLayout);
            app.NumofbodiesLabel = uilabel(app.NumBodiesLayout);
            app.NumofbodiesLabel.Text = 'Num. of bodies:';
            app.NumBodiesField = uispinner(app.NumBodiesLayout);
            app.NumBodiesField.Limits = [2 30];
            app.NumBodiesField.ValueDisplayFormat = '%.0f';
            app.NumBodiesField.ValueChangedFcn = createCallbackFcn(app, @OnResetPressed, true);
            app.NumBodiesField.Tooltip = {'Number of celestial bodies in the simulation'};
            app.NumBodiesField.Value = 8;
            % Show the figure after all components are created
            app.NBodySimulator.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NBS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NBodySimulator)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NBodySimulator)
        end
    end
end